classdef ImageResto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PreImage                      matlab.ui.control.UIAxes
        PostImage                     matlab.ui.control.UIAxes
        KernelAxes                    matlab.ui.control.UIAxes
        PreProcessTextAreaLabel       matlab.ui.control.Label
        PreProcessPsnr                matlab.ui.control.TextArea
        PostProcessTextAreaLabel      matlab.ui.control.Label
        PostProcessPsnr               matlab.ui.control.TextArea
        PSNRLabel                     matlab.ui.control.Label
        SSIMLabel                     matlab.ui.control.Label
        Kernel1Button                 matlab.ui.control.Button
        Kernel2Button                 matlab.ui.control.Button
        Kernel3Button                 matlab.ui.control.Button
        Kernel4Button                 matlab.ui.control.Button
        RestorationTypeDropDownLabel  matlab.ui.control.Label
        RestorationTypeDropDown       matlab.ui.control.DropDown
        Image1Button                  matlab.ui.control.Button
        Image2Button                  matlab.ui.control.Button
        Image3Button                  matlab.ui.control.Button
        Image4Button                  matlab.ui.control.Button
        ChooseButtonGroup             matlab.ui.container.ButtonGroup
        SubImage1Button               matlab.ui.control.RadioButton
        SubImage2Button               matlab.ui.control.RadioButton
        SubImage3Button               matlab.ui.control.RadioButton
        SubImage4Button               matlab.ui.control.RadioButton
        KnobLabel                     matlab.ui.control.Label
        Knob                          matlab.ui.control.Knob
        PreProcessTextAreaLabel_2     matlab.ui.control.Label
        PreProcessSsim                matlab.ui.control.TextArea
        PostProcessTextAreaLabel_2    matlab.ui.control.Label
        PostProcessSsim               matlab.ui.control.TextArea
        LoadDataButton                matlab.ui.control.Button
        KnobValueEditFieldLabel       matlab.ui.control.Label
        KnobValueEditField            matlab.ui.control.NumericEditField
        ApplyChangesButton            matlab.ui.control.Button
        RunningStatusEditFieldLabel   matlab.ui.control.Label
        RunningStatusEditField        matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Kernel1Button
        function Kernel1ButtonPushed(app, event)
            global orig_kernel;% Global variable for kernel to process
            %read the kernel 
            kernel=im2double(imread('Kernel1G.png'));
            %truncate the kernel in its non-zero area
            orig_kernel=kernel(31:46,30:40);
            %show the kernel on kernel axes
            imshow(orig_kernel,'Parent',app.KernelAxes);
            orig_kernel=orig_kernel./sum(sum(orig_kernel));
        end

        % Button pushed function: Kernel2Button
        function Kernel2ButtonPushed(app, event)
            global orig_kernel;% Global variable for kernel to process
            %read the kernel 
            kernel=im2double(imread('Kernel2G.png'));
            %truncate the kernel in its non-zero area
            orig_kernel=kernel(22:34,29:40);
            %show the kernel on kernel axes
            imshow(orig_kernel,'Parent',app.KernelAxes);
            orig_kernel=orig_kernel./sum(sum(orig_kernel));
        end

        % Button pushed function: Kernel3Button
        function Kernel3ButtonPushed(app, event)
            global orig_kernel;% Global variable for kernel to process
            %read the kernel 
            kernel=im2double(imread('Kernel3G.png'));
            %truncate the kernel in its non-zero area
            orig_kernel=kernel(27:34,36:41);
            %show the kernel on kernel axes
            imshow(orig_kernel,'Parent',app.KernelAxes);
            orig_kernel=orig_kernel./sum(sum(orig_kernel));
        end

        % Button pushed function: Kernel4Button
        function Kernel4ButtonPushed(app, event)
            global orig_kernel;% Global variable for kernel to process
            %read the kernel 
            kernel=im2double(imread('Kernel4G.png'));
            %truncate the kernel in its non-zero area
            orig_kernel=kernel(26:35,38:42);
            %show the kernel on kernel axes
            imshow(orig_kernel,'Parent',app.KernelAxes);
            orig_kernel=orig_kernel./sum(sum(orig_kernel));
        end

        % Button pushed function: Image1Button
        function Image1ButtonPushed(app, event)
            global orig_image;  % Global Varible for Image to Process
            global glob_M;      % No of rows
            global glob_N;      % No of cols
            global glob_Z;      % No of channels
            global GroundTruth; % Global Varible for Groundtruth Image
            %Read the subimage according to given Input button
            if(app.SubImage1Button.Value == true)
                orig_image=im2double(imread('Blurry1_1.jpg'));
            elseif(app.SubImage2Button.Value == true)
                orig_image=im2double(imread('Blurry1_2.jpg'));
            elseif(app.SubImage3Button.Value == true)
                orig_image=im2double(imread('Blurry1_3.jpg'));
            else
                orig_image=im2double(imread('Blurry1_4.jpg'));
            end
            %Read the Groundtruth according to given Input button
            GroundTruth=im2double(imread('GroundTruth1_1_1.jpg'));
            [glob_M,glob_N,glob_Z]=size(orig_image);
            %Show the input blurry image on PreImageAxes
            imshow(orig_image,'Parent',app.PreImage);
        end

        % Button pushed function: Image2Button
        function Image2ButtonPushed(app, event)
            global orig_image;  % Global Varible for Image to Process
            global glob_M;      % No of rows
            global glob_N;      % No of cols
            global glob_Z;      % No of channels
            global GroundTruth; % Global Varible for Groundtruth Image
            %Read the subimage according to given Input button
            if(app.SubImage1Button.Value == true)
                orig_image=im2double(imread('Blurry2_1.jpg'));
            elseif(app.SubImage2Button.Value == true)
                orig_image=im2double(imread('Blurry2_2.jpg'));
            elseif(app.SubImage3Button.Value == true)
                orig_image=im2double(imread('Blurry2_3.jpg'));
            else
                orig_image=im2double(imread('Blurry2_4.jpg'));
            end
            %Read the Groundtruth according to given Input button
            GroundTruth=im2double(imread('GroundTruth2_1_1.jpg'));
            [glob_M,glob_N,glob_Z]=size(orig_image);
            %Show the input blurry image on PreImageAxes
            imshow(orig_image,'Parent',app.PreImage);
        end

        % Button pushed function: Image3Button
        function Image3ButtonPushed(app, event)
            global orig_image;  % Global Varible for Image to Process
            global glob_M;      % No of rows
            global glob_N;      % No of cols
            global glob_Z;      % No of channels
            global GroundTruth; % Global Varible for Groundtruth Image
            %Read the subimage according to given Input button
            if(app.SubImage1Button.Value == true)
                orig_image=im2double(imread('Blurry3_1.jpg'));
            elseif(app.SubImage2Button.Value == true)
                orig_image=im2double(imread('Blurry3_2.jpg'));
            elseif(app.SubImage3Button.Value == true)
                orig_image=im2double(imread('Blurry3_3.jpg'));
            else
                orig_image=im2double(imread('Blurry3_4.jpg'));
            end
            %Read the Groundtruth according to given Input button
            GroundTruth=im2double(imread('GroundTruth3_1_1.jpg'));
            [glob_M,glob_N,glob_Z]=size(orig_image);
            %Show the input blurry image on PreImageAxes
            imshow(orig_image,'Parent',app.PreImage);
        end

        % Button pushed function: Image4Button
        function Image4ButtonPushed(app, event)
            global orig_image;  % Global Varible for Image to Process
            global glob_M;      % No of rows
            global glob_N;      % No of cols
            global glob_Z;      % No of channels
            global GroundTruth; % Global Varible for Groundtruth Image
            %Read the subimage according to given Input button
            if(app.SubImage1Button.Value == true)
                orig_image=im2double(imread('Blurry4_1.jpg'));
            elseif(app.SubImage2Button.Value == true)
                orig_image=im2double(imread('Blurry4_2.jpg'));
            elseif(app.SubImage3Button.Value == true)
                orig_image=im2double(imread('Blurry4_3.jpg'));
            else
                orig_image=im2double(imread('Blurry4_4.jpg'));
            end
            %Read the Groundtruth according to given Input button
            GroundTruth=im2double(imread('GroundTruth4_1_1.jpg'));
            [glob_M,glob_N,glob_Z]=size(orig_image);
            %Show the input blurry image on PreImageAxes
            imshow(orig_image,'Parent',app.PreImage);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.RunningStatusEditField.Value = 'Loading...';
            global orig_image;  % Global Varible for Image to Process
            global glob_M;      % No of rows
            global glob_N;      % No of cols
            global glob_Z;      % No of channels
            global GroundTruth; % Global Varible for Groundtruth Image
            global orig_kernel; % Global variable for kernel to process
            global kernel_fft;  %fft of kernel 
            global image_fft;   %fft of blurry image
            %initialize the matrix to store DFT
            image_fft=ones(glob_M,glob_N,glob_Z);
            %Calculate the DFT of the image
            for i=1:glob_Z
                image_fft(:,:,i)=Myfft(orig_image(:,:,i));
            end
            %Calculate the DFT of the kernel in image size 
            kernel_fft=Myfft(orig_kernel,glob_M,glob_N);
            %Calculate PSRN of the input image
            peaksnr_cal = Mypsnr(orig_image,GroundTruth);
            
            app.PreProcessPsnr.Value = {num2str(peaksnr_cal)};
            %Calculate SSIM of the input image
            ssim_cal=Myssim(orig_image,GroundTruth);
            app.PreProcessSsim.Value = {num2str(ssim_cal)};
            app.RunningStatusEditField.Value = 'Loading Done!!';
        end

        % Value changed function: RestorationTypeDropDown
        function RestorationTypeDropDownValueChanged(app, event)
            value = app.RestorationTypeDropDown.Value;
            %Check which type of restoration technique choosen
            if strcmp(value,'Full Inverse Flitering')
                %set limits of the knob and default value
                app.Knob.Limits = [0 1];
                app.KnobValueEditField.Value = 0.01;
            elseif strcmp(value,'Inverse Filtering')
                %set limits of the knob and default value
                app.Knob.Limits = [10 700];
                app.Knob.Value = 70;
                app.KnobValueEditField.Value = 70;
            elseif strcmp(value,'Constrained Least Square')
                %set limits of the knob and default value
                app.Knob.Limits = [0 5];
                app.Knob.Value = 0.5;
                app.KnobValueEditField.Value = 0.5;
            elseif strcmp(value,'Weiner Filter')
                %set limits of the knob and default value
                app.Knob.Limits = [0 1];
                app.Knob.Value = 0.5;
                app.KnobValueEditField.Value = 0.5;
            else
                disp('Default Restoration!!')
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            % Display the changed value of knob
            app.KnobValueEditField.Value = value;
        end

        % Callback function
        function ApplyImageRestorationButtonValueChanged(app, event)
            value = app.ApplyImageRestorationButton.Value;        
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            global orig_image;  % Global Varible for Image to Process
            global GroundTruth; % Global Varible for Groundtruth Image
            global kernel_fft;  %fft of kernel 
            global image_fft;   %fft of blurry image
            global result_image;
            app.RunningStatusEditField.Value = 'Restoration Applying...';
            value = app.RestorationTypeDropDown.Value;
            %Check which type of restoration technique choosen
            if strcmp(value,'Full Inverse Flitering')
                % Applying full inverse filtering
                result_image=FullInverseFilt(kernel_fft,image_fft);
            elseif strcmp(value,'Inverse Filtering')
                % Applying inverse filtering with truncation in freq.domain
                full_inv_image=FullInverseFilt(kernel_fft,image_fft);
                result_image=butterWorth(full_inv_image,app.Knob.Value,10);                
            elseif strcmp(value,'Constrained Least Square')
                % Applying Constrained Least Square filtrering
                result_image=LMS(kernel_fft,image_fft,app.Knob.Value);
            elseif strcmp(value,'Weiner Filter')
                % Applying Weiner filtering
                result_image=WeinerFilter(kernel_fft,image_fft,app.Knob.Value);
            else
                disp('Default Restoration!!')
                result_image=orig_image;
            end
            imshow(result_image,'Parent',app.PostImage);
            %Calculate PSRN of the input image
            peaksnr_cal = Mypsnr(result_image,GroundTruth);
            app.PostProcessPsnr.Value = {num2str(peaksnr_cal)};
            %Calculate SSIM of the input image
            ssim_cal=Myssim(result_image,GroundTruth);
            app.PostProcessSsim.Value = {num2str(ssim_cal)};
            app.RunningStatusEditField.Value = 'Restoration Done!!';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global result_image;
            %pop up the dialog box for saving file
            [file_output,typename]=imputfile(); 
            %if Esc/close is pressed on dialog box
            if size(file_output)== [0 0]        
                app.RunningStatusEditField.Value='Did not saved';
            else
                 % save the image in the file name provided above
                imwrite(result_image,file_output);
                app.RunningStatusEditField.Value='Image Saved Successfully'; 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PreImage = uiaxes(app.UIFigure);
            title(app.PreImage, 'Original Blurry Image')
            xlabel(app.PreImage, 'X')
            ylabel(app.PreImage, 'Y')
            app.PostImage = uiaxes(app.UIFigure);
            title(app.PostImage, 'Processed Image')
            xlabel(app.PostImage, 'X')
            ylabel(app.PostImage, 'Y')
            app.KernelAxes = uiaxes(app.UIFigure);
            title(app.KernelAxes, 'Kernel')
            xlabel(app.KernelAxes, 'X')
            ylabel(app.KernelAxes, 'Y')
            app.PreProcessTextAreaLabel = uilabel(app.UIFigure);
            app.PreProcessTextAreaLabel.Text = 'PreProcess';
            app.PreProcessPsnr = uitextarea(app.UIFigure);
            app.PostProcessTextAreaLabel = uilabel(app.UIFigure);
            app.PostProcessTextAreaLabel.Text = 'PostProcess';
            app.PostProcessPsnr = uitextarea(app.UIFigure);
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR';
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM';
            app.Kernel1Button = uibutton(app.UIFigure, 'push');
            app.Kernel1Button.ButtonPushedFcn = createCallbackFcn(app, @Kernel1ButtonPushed, true);
            app.Kernel1Button.Text = 'Kernel1';
            app.Kernel2Button = uibutton(app.UIFigure, 'push');
            app.Kernel2Button.ButtonPushedFcn = createCallbackFcn(app, @Kernel2ButtonPushed, true);
            app.Kernel2Button.Text = 'Kernel2';
            app.Kernel3Button = uibutton(app.UIFigure, 'push');
            app.Kernel3Button.ButtonPushedFcn = createCallbackFcn(app, @Kernel3ButtonPushed, true);
            app.Kernel3Button.Text = 'Kernel3';
            app.Kernel4Button = uibutton(app.UIFigure, 'push');
            app.Kernel4Button.ButtonPushedFcn = createCallbackFcn(app, @Kernel4ButtonPushed, true);
            app.Kernel4Button.Text = 'Kernel4';
            app.RestorationTypeDropDownLabel = uilabel(app.UIFigure);
            app.RestorationTypeDropDownLabel.Text = 'Restoration Type';
            app.RestorationTypeDropDown = uidropdown(app.UIFigure);
            app.RestorationTypeDropDown.Items = {'Select', 'Full Inverse Flitering', 'Inverse Filtering', 'Weiner Filter', 'Constrained Least Square'};
            app.RestorationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RestorationTypeDropDownValueChanged, true);
            app.RestorationTypeDropDown.Value = 'Select';
            app.Image1Button = uibutton(app.UIFigure, 'push');
            app.Image1Button.ButtonPushedFcn = createCallbackFcn(app, @Image1ButtonPushed, true);
            app.Image1Button.Text = 'Image1';
            app.Image2Button = uibutton(app.UIFigure, 'push');
            app.Image2Button.ButtonPushedFcn = createCallbackFcn(app, @Image2ButtonPushed, true);
            app.Image2Button.Text = 'Image2';
            app.Image3Button = uibutton(app.UIFigure, 'push');
            app.Image3Button.ButtonPushedFcn = createCallbackFcn(app, @Image3ButtonPushed, true);
            app.Image3Button.Text = 'Image3';
            app.Image4Button = uibutton(app.UIFigure, 'push');
            app.Image4Button.ButtonPushedFcn = createCallbackFcn(app, @Image4ButtonPushed, true);
            app.Image4Button.Text = 'Image4';
            app.ChooseButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseButtonGroup.Title = 'Choose:';
            app.SubImage1Button = uiradiobutton(app.ChooseButtonGroup);
            app.SubImage1Button.Text = 'Sub Image 1';
            app.SubImage1Button.Value = true;
            app.SubImage2Button = uiradiobutton(app.ChooseButtonGroup);
            app.SubImage2Button.Text = 'Sub Image 2';
            app.SubImage3Button = uiradiobutton(app.ChooseButtonGroup);
            app.SubImage3Button.Text = 'Sub Image 3';
            app.SubImage4Button = uiradiobutton(app.ChooseButtonGroup);
            app.SubImage4Button.Text = 'Sub Image 4';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 10];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.PreProcessTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PreProcessTextAreaLabel_2.Text = 'PreProcess';
            app.PreProcessSsim = uitextarea(app.UIFigure);
            app.PostProcessTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PostProcessTextAreaLabel_2.Text = 'PostProcess';
            app.PostProcessSsim = uitextarea(app.UIFigure);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.KnobValueEditFieldLabel = uilabel(app.UIFigure);
            app.KnobValueEditFieldLabel.Text = 'Knob Value';
            app.KnobValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.KnobValueEditField.Editable = 'off';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.RunningStatusEditFieldLabel = uilabel(app.UIFigure);
            app.RunningStatusEditFieldLabel.Text = 'Running Status';
            app.RunningStatusEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageResto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end